classdef TISI_Mark_Detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SAMSANTISIMarkDetectionUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        FileSelectionLabel      matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        ExitButton              matlab.ui.control.Button
        ProcessButton           matlab.ui.control.Button
        LoadImageButton         matlab.ui.control.Button
        EditField               matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        RightPanel              matlab.ui.container.Panel
        EditField_2             matlab.ui.control.EditField
        EditField_2Label        matlab.ui.control.Label
        ResultLabel             matlab.ui.control.Label
        TyreMarkDetectionLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        ImageFile % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            
            
    app.EditField .Value='';
    app.EditField_2 .Value='';
    app.EditField_2.BackgroundColor='white'; 
            
            
            [filename,filepath] = uigetfile({'*,*';'*.jpg';'*.png';'*.bmp';'*.oct'}, 'Select File to Open');
            fullname = [filepath, filename];
             app.EditField.Value = fullname;
            app.ImageFile = rgb2gray(imread(fullname));
            imshow(app.ImageFile ,'Parent',app.UIAxes,'InitialMagnification','fit')
            %imagesc(app.UIAxes, app.ImageFile);
            %axis off;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
                
            Im_Original=app.ImageFile;
            Im=imcomplement(Im_Original);
            Imc=Im;
            ImORG=Im;
            imshow(Im_Original,'Parent',app.UIAxes)
            %imagesc(app.UIAxes, Im_Original);
            Val=-1;
            Test_pass=0;
            passcount=0;
        
            %file=fullname(end-11:end-4);
            
          
    set(app.SAMSANTISIMarkDetectionUIFigure,'Pointer','watch'); drawnow;
    app.ProcessButton.Text = 'Processing...';

            for c=60:72
                app.ProcessButton.Text = num2str(c);
                disp(c)
                IRot=Im;
                Im=imrotate(Im,-c.*5,"crop"); %, 'loose');
                im_roi=imcrop(Im,[900,350,550,130]);
                ImO=imrotate(Im_Original,-c.*5,"crop"); 
                im_roi_O=imcrop(ImO,[900,350,550,130]);
                im_roi_O_org=im_roi_O;
                out=false;
                detect=0;

                while out==false && detect==0
                    for exp= 50:50
                            Ip= im_roi; 
                            Ip(Ip<10)=0;
                            se = offsetstrel('ball',14,14) ;% 14, 14
                            BW2 = imdilate(Ip,se);
                            level = graythresh(BW2);
                            level=level+(0.05)*level;
                            
                            I_F = imbinarize(BW2,level);
                            cc = bwconncomp(I_F); 
                            
                            stats = regionprops(cc,'Area','Eccentricity','Orientation','FilledImage','PixelList', 'BoundingBox','Centroid','MajorAxisLength','MinorAxisLength');
                            if length(stats)==0 
                                out=true
                                break
                            end
                            for i=1: length(stats)
                                stats(i).Ratio = stats(i).MajorAxisLength/stats(i).MinorAxisLength;
                                stats(i).BBX= stats(i).BoundingBox(1);
                                stats(i).BBY= stats(i).BoundingBox(2);
                                stats(i).CenX= stats(i).Centroid(1);
                                stats(i).CenY= stats(i).Centroid(2);
                                stats(i).BBXRange=stats(i).BoundingBox(1)+stats(i).BoundingBox(3);
                                stats(i).BBYRange=stats(i).BoundingBox(2)+stats(i).BoundingBox(4);
                            end
                                %idx=find([stats.Area]>3000  & [stats.Area]<6000 &[stats.Orientation]>8 &[stats.Orientation]<25 &  [stats.BBY] >30 &  [stats.BBX] >60 & [stats.Ratio]>2.3 & [stats.Ratio]<2.7);
                                %idx=find([stats.Area]>3000  & [stats.Area]<6000 &[stats.Orientation]>8 &[stats.Orientation]<25 &  [stats.BBY] <75 &  [stats.BBY] >30 &  [stats.BBX] >60 & [stats.Ratio]>2.0 & [stats.Ratio]<2.7);
                                
                                idx=find([stats.Area]>3000  & [stats.Area]<6000 &[stats.Orientation]>8 &[stats.Orientation]<25 &  [stats.BBY] <85 &  [stats.BBY] >30 &  [stats.BBX] >60 & [stats.Ratio]>2.0 & [stats.Ratio]<2.7 & [stats.MajorAxisLength]<240 & [stats.MajorAxisLength]>90);
                                BW2 = ismember(labelmatrix(cc),idx);    
                                IFinal_comp= im2uint8(imcomplement(BW2));
                                stats_results=stats(idx,:);
                                % if length(stats_results) >0
                                %     1
                                % end
                               %figure(10)
                               %Visualizing_region_properties_blob_Anaysis(im_roi_O,stats_results,0,0,im_roi_O,app, 'n');
                               % Visualizing_region_properties_blob_Anaysis(Ip,stats,0,0,Ip);
                           
                                    for i=1: length(stats_results)
                                        Imt=imcrop(im_roi, stats(i).BoundingBox);
                                        E = entropy(Imt);
                                        glcms=graycomatrix(Imt);
                                        stats_temp = graycoprops(glcms, {'Contrast', 'Correlation', 'Energy', 'Homogeneity'});
                                        if E<6.2 &stats_temp.Energy<0.75 &stats_temp.Energy>0.2 %% &stats_temp.Energ
                                                imLogo=(im_roi.*(uint8(BW2)));
                                                % My_Visualizing_region_properties_blob_Anaysis(im_roi_O,stats_results(i),0,0,BW);
                                                stats_temp = graycoprops(glcms, {'Contrast', 'Correlation', 'Energy', 'Homogeneity'});

                                                [centers, radii, metric] = imfindcircles(im_roi,[2 1000]);
                                                Im12=imrotate(Im_Original,-c.*5,"crop"); %, 'loose');
                                                Visualizing_region_properties_blob_Anaysis(im_roi_O,stats_results(i),900,350,Im12,app, 'y');
                                                

                                        if radii<15
                                                if find(centers(:,1)>stats_results(i).BoundingBox(1) & centers(:,1)<stats_results(i).BBXRange & centers(:,2)>stats_results(i).BoundingBox(2) & centers(:,2)<stats_results(i).BBYRange)
                                                    ccircle=find(centers(:,1)>stats_results(i).BoundingBox(1) & centers(:,1)<stats_results(i).BBXRange & centers(:,2)>stats_results(i).BoundingBox(2) & centers(:,2)<stats_results(i).BBYRange);
                                                    imshow(Im12,'Parent',app.UIAxes)
                                                    myviscircles(app, centers(ccircle,:), radii(ccircle,:)+8);

                                                    app.EditField_2.Value = 'TISI Mark Detected';
                                                    app.EditField_2.BackgroundColor='green';  %,'FontSize', 11,'ForegroundColor', 'w','FontWeight','bold')
                                                    %for k = 1:3
                                                        % %writeLED(rpi,'led1',1);
                                                        % writeDigitalPin(rpi,21, 1)
                                                        % pause(0.5);
                                                        % writeDigitalPin(rpi, 21, 0)
                                                        % %writeLED(rpi,'led1',1);
                                                        % pause(0.5);
                                                    %end
                                                     
                                                     passcount=passcount+1;
                                                     detect=1;
                                                     Im12=imrotate(Im_Original,-c.*5,"crop"); %, 'loose');
                                                     %figure(11)
                                                     Visualizing_region_properties_blob_Anaysis(im_roi_O,stats_results(i),900,350,Im12,app, 'y');
                                                                                        
        
                                            %  t = datetime('now', 'Format','yyyy_MM_dd,HH_mm');
                                            %     DateString = datestr(t)
                                            % FileName=[DateString,'.fig']
                                            %  FileName=['C:\Users\admin\Desktop\Matlab Examples\TISI\Tyre Images\Tyre Images\SameSize_Mark\ResultFile\',FileName]
                                            % 
                                            %  fignew = figure('Visible','off'); % Invisible figure
                                            % newAxes = copyobj(handles.axes2,fignew); % Copy the appropriate axes
                                            % set(newAxes,'Position',get(groot,'DefaultAxesPosition')); % The original position is copied too, so adjust it.
                                            % set(fignew,'CreateFcn','set(gcbf,''Visible'',''on'')'); % Make it visible upon loadin
                                                
                                        % F = getframe(app.UIAxes)
                                        % displayedImage = frame2im(F)
                                                end
                                        end
                                        
                                        
                                   end
                                    end
                                Im=Imc;
                                im_roi_O=im_roi_O_org;        
                    end
                    out=true;
                end
            if detect==1
               break
            end  
             Im= IRot;
            end


        set(app.SAMSANTISIMarkDetectionUIFigure,'Pointer','arrow'); drawnow;
    app.ProcessButton.Text = 'Process';
    
        %delete(f)
        if passcount==0
            app.EditField_2.Value = 'TISI Mark Not Detected';
            app.EditField_2.BackgroundColor='red'; 
            app.EditField_2.FontWeight= 'bold';
        else
            app.EditField_2.Value = 'TISI Mark Detected';
            app.EditField_2.BackgroundColor='green';  
            pp.EditField_2.FontWeight= 'bold';
        end


function Visualizing_region_properties_blob_Anaysis(bw,stats_rsults, xadd, yadd, Im,app, appd)
% imshow(bw)
% hold on
if appd=='y'
%imshow(Im,'Parent',app.UIAxes)
%Imshow(Im)
hold(app.UIAxes,"on")
else
    imshow(Im)
    hold on
end

phi = linspace(0,2*pi,50);
cosphi = cos(phi);
sinphi = sin(phi);
    
    for k = 1:length(stats_rsults)
        xbar = stats_rsults(k).Centroid(1);
        ybar = stats_rsults(k).Centroid(2);
    
        a =stats_rsults(k).MajorAxisLength/2;
        b =stats_rsults(k).MinorAxisLength/2;
    
        theta = pi*stats_rsults(k).Orientation/180;
        R = [ cos(theta)   sin(theta)
             -sin(theta)   cos(theta)];
    
        xy = [a*cosphi; b*sinphi];
        xy = R*xy;
    
        x = xy(1,:) + xbar+xadd;
        y = xy(2,:) + ybar+yadd;
        
        if appd=='y'
            plot(app.UIAxes,x,y,'r','LineWidth',2);
        else
            plot(x,y,'r','LineWidth',2)
        end
        
    end
end

function h = myviscircles(app, centers, radii )

if isempty(centers)
    h = [];
    return;
end

hold(app.UIAxes,"on")
thetaResolution = 2; 
theta=(0:thetaResolution:360)'*pi/180;

x = radii' .* cos(theta);
x = x + (centers(:,1))';
x = cat(1,x,nan(1,1));
x = x(:);


y = radii' .* sin(theta);
y = y + (centers(:,2))';
y = cat(1,y,nan(1,1));
y = y(:);

x=x+900;
y=y+350;

hold(app.UIAxes,"on")
plot(app.UIAxes,x,y,'b','LineWidth',2);

end


        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.SAMSANTISIMarkDetectionUIFigure)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SAMSANTISIMarkDetectionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {642, 642};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {379, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SAMSANTISIMarkDetectionUIFigure = uifigure('Visible', 'off');
            app.SAMSANTISIMarkDetectionUIFigure.Name = 'SAMSAN-TISI Mark Detection ';
            app.SAMSANTISIMarkDetectionUIFigure.Icon = fullfile(pathToMLAPP, 'samsan_logo.jpg');
            app.SAMSANTISIMarkDetectionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SAMSANTISIMarkDetectionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'TISI_Mark.png';
            app.Panel = uipanel(app.LeftPanel);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.EditField = uieditfield(app.Panel, 'text');
            app.LoadImageButton = uibutton(app.Panel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Tag = 'LoadImage';
            app.LoadImageButton.Text = 'Load Image';
            app.ProcessButton = uibutton(app.Panel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Tag = 'Process';
            app.ProcessButton.Text = ' Process';
            app.ExitButton = uibutton(app.Panel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Tag = 'Process';
            app.ExitButton.Text = 'Exit';
            app.FileSelectionLabel = uilabel(app.LeftPanel);
            app.FileSelectionLabel.Text = 'File Selection';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TyreMarkDetectionLabel = uilabel(app.RightPanel);
            app.TyreMarkDetectionLabel.Text = 'Tyre Mark Detection';
            app.ResultLabel = uilabel(app.RightPanel);
            app.ResultLabel.Text = 'Result';
            app.EditField_2Label = uilabel(app.RightPanel);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.RightPanel, 'text');
            % Show the figure after all components are created
            app.SAMSANTISIMarkDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TISI_Mark_Detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SAMSANTISIMarkDetectionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SAMSANTISIMarkDetectionUIFigure)
        end
    end
end